classdef DSP_PROJECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExitImage                       matlab.ui.control.Image
        Label_7                         matlab.ui.control.Label
        SEMGANALYSISTOOLBOXLabel        matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        MVCNormalisationButton          matlab.ui.control.ToggleButton
        NormalisationLabel              matlab.ui.control.Label
        ImageB                          matlab.ui.control.Image
        ImageA                          matlab.ui.control.Image
        ImageOutlier                    matlab.ui.control.Image
        ImageFastFourrier               matlab.ui.control.Image
        ImageConversion                 matlab.ui.control.Image
        imageImport                     matlab.ui.control.Image
        ProcessingLabel                 matlab.ui.control.Label
        TrackBButton                    matlab.ui.control.ToggleButton
        TrackAButton                    matlab.ui.control.ToggleButton
        OutlierremovalButton            matlab.ui.control.ToggleButton
        FastFourrierButton              matlab.ui.control.ToggleButton
        ConversionButton                matlab.ui.control.ToggleButton
        ImportdataButton                matlab.ui.control.ToggleButton
        TabGroup                        matlab.ui.container.TabGroup
        RAWDATATab                      matlab.ui.container.Tab
        SelectfileButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        YourdataLabel                   matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        CH1Tab                          matlab.ui.container.Tab
        RawDataChannel1                 matlab.ui.control.UIAxes
        CH2Tab                          matlab.ui.container.Tab
        RawDataChannel2                 matlab.ui.control.UIAxes
        CH3Tab                          matlab.ui.container.Tab
        RawDataChannel3                 matlab.ui.control.UIAxes
        CH4Tab                          matlab.ui.container.Tab
        RawDataChannel4                 matlab.ui.control.UIAxes
        CH5Tab                          matlab.ui.container.Tab
        RawDataChannel5                 matlab.ui.control.UIAxes
        mVDATATab                       matlab.ui.container.Tab
        ConverttomVButton               matlab.ui.control.Button
        mVDATALabel                     matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        TabGroup2_2                     matlab.ui.container.TabGroup
        CH1Tab_2                        matlab.ui.container.Tab
        MvDataChannel1                  matlab.ui.control.UIAxes
        CH2Tab_2                        matlab.ui.container.Tab
        MvDataChannel2                  matlab.ui.control.UIAxes
        CH3Tab_2                        matlab.ui.container.Tab
        MvDataChannel3                  matlab.ui.control.UIAxes
        CH4Tab_2                        matlab.ui.container.Tab
        MvDataChannel4                  matlab.ui.control.UIAxes
        CH5Tab_2                        matlab.ui.container.Tab
        MvDataChannel5                  matlab.ui.control.UIAxes
        FFTANALYSISTab                  matlab.ui.container.Tab
        FFTButton                       matlab.ui.control.Button
        TabGroup2_3                     matlab.ui.container.TabGroup
        CH1Tab_3                        matlab.ui.container.Tab
        FFTDataChannel1                 matlab.ui.control.UIAxes
        CH2Tab_3                        matlab.ui.container.Tab
        FFTDataChannel2                 matlab.ui.control.UIAxes
        CH3Tab_3                        matlab.ui.container.Tab
        FFTDataChannel3                 matlab.ui.control.UIAxes
        CH4Tab_6                        matlab.ui.container.Tab
        FFTDataChannel4                 matlab.ui.control.UIAxes
        CH5Tab_3                        matlab.ui.container.Tab
        FFTDataChannel5                 matlab.ui.control.UIAxes
        REMOVEOUTLIERTab                matlab.ui.container.Tab
        DocumentationOutliers           matlab.ui.control.TextArea
        DocumentationTextAreaLabel      matlab.ui.control.Label
        REMOVEOUTLIERButton             matlab.ui.control.Button
        FillmethodLabel                 matlab.ui.control.Label
        MethodfordetectingoutliersLabel  matlab.ui.control.Label
        FillOutlierOptionDropdown       matlab.ui.control.DropDown
        RemoveOutlierOptionDropdown     matlab.ui.control.DropDown
        OutlierDeviationOptionDropdown  matlab.ui.control.DropDown
        OutlieroptionsLabel             matlab.ui.control.Label
        StandarddeviationLabel          matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        TabGroup2_4                     matlab.ui.container.TabGroup
        CH1Tab_4                        matlab.ui.container.Tab
        OutlierDataChannel1             matlab.ui.control.UIAxes
        CH2Tab_4                        matlab.ui.container.Tab
        OutlierDataChannel2             matlab.ui.control.UIAxes
        CH3Tab_4                        matlab.ui.container.Tab
        OutlierDataChannel3             matlab.ui.control.UIAxes
        CH4Tab_4                        matlab.ui.container.Tab
        OutlierDataChannel4             matlab.ui.control.UIAxes
        CH5Tab_4                        matlab.ui.container.Tab
        OutlierDataChannel5             matlab.ui.control.UIAxes
        PROCESSINGTRACKATab             matlab.ui.container.Tab
        ExportButton                    matlab.ui.control.Button
        DocumentationTrackA             matlab.ui.control.TextArea
        DocumentationTextAreaLabel_2    matlab.ui.control.Label
        EXPORTDATAAFTERMVCButton        matlab.ui.control.Button
        Cuttofffrequency2Label          matlab.ui.control.Label
        Cuttofffrequency1Label          matlab.ui.control.Label
        TrackA_FC1                      matlab.ui.control.NumericEditField
        TrackA_FC2                      matlab.ui.control.NumericEditField
        FilterorderLabel                matlab.ui.control.Label
        FilterOrderDropdown             matlab.ui.control.DropDown
        FilterTypeDropdown              matlab.ui.control.DropDown
        ApplyfilterButton               matlab.ui.control.Button
        FiltertypeLabel                 matlab.ui.control.Label
        FilteroptionsLabel              matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        TabGroup2_5                     matlab.ui.container.TabGroup
        CH1Tab_5                        matlab.ui.container.Tab
        TrackAchannel1                  matlab.ui.control.UIAxes
        CH2Tab_5                        matlab.ui.container.Tab
        TrackAchannel2                  matlab.ui.control.UIAxes
        CH3Tab_5                        matlab.ui.container.Tab
        TrackAchannel3                  matlab.ui.control.UIAxes
        CH4Tab_5                        matlab.ui.container.Tab
        TrackAchannel4                  matlab.ui.control.UIAxes
        CH5Tab_5                        matlab.ui.container.Tab
        TrackAchannel5                  matlab.ui.control.UIAxes
        PROCESSINGTRACKBTab             matlab.ui.container.Tab
        PROCESSINGTRACKBButton          matlab.ui.control.Button
        MVCNORMALISATIONTab             matlab.ui.container.Tab
        MVCNORMALISATIONButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        mVDataMatrix % Description
        nSeq % Description
        FFTDataMatrix % Description
        rawChannelData % Description
        FilterType = 'hp'; % Description
        FilterOrder = 1; % Description
        Channel1FFTData % Description
        Channel2FFTData % Description
        Channel3FFTData % Description
        Channel4FFTData % Description
        Channel5FFTData % Description
        f % Description
        fillOutlierOption = "center" % Description
        removeOutlierOption = "median" % Description
        thresholdFactor = 1 % Description
        SettingsUsed = "{Settings:"; % Description
        fs % Description
        SettingsCounter = 0; % Description
    end
    
    methods (Access = private)
        
        
        function ResetButtonColor(app)
            app.ImportdataButton.FontColor = '0.94,0.94,0.94';
            app.ImportdataButton.BackgroundColor = '0.07,0.17,0.24';
            app.ConversionButton.FontColor = '0.94,0.94,0.94';
            app.ConversionButton.BackgroundColor = '0.07,0.17,0.24';
            app.FastFourrierButton.FontColor = '0.94,0.94,0.94';
            app.FastFourrierButton.BackgroundColor = '0.07,0.17,0.24';
            app.OutlierremovalButton.FontColor = '0.94,0.94,0.94';
            app.OutlierremovalButton.BackgroundColor = '0.07,0.17,0.24';
            app.TrackAButton.FontColor = '0.94,0.94,0.94';
            app.TrackAButton.BackgroundColor = '0.07,0.17,0.24';
            app.TrackBButton.FontColor = '0.94,0.94,0.94';
            app.TrackBButton.BackgroundColor = '0.07,0.17,0.24';
            app.MVCNormalisationButton.FontColor = '0.94,0.94,0.94';
            app.MVCNormalisationButton.BackgroundColor = '0.07,0.17,0.24';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)

[filename,path]=uigetfile("DATA/PP01/*.txt");
pathToFile = path + "/" + filename;
fileID = fopen(pathToFile);
loadedData = readmatrix(pathToFile);
%vars = {'nSeq','DI','CH1','CH2','CH3','CH4','CH5'};
%app.UITable.ColumnName = vars;
app.UITable.Data = loadedData;
fclose(fileID);
figure(app.UIFigure);

%STAP 2
app.rawChannelData = loadedData(:,3:end);
app.nSeq = loadedData(:,1);
app.YourdataLabel.Visible = 'on';

channel1RawData = app.rawChannelData(:,1);
channel2RawData = app.rawChannelData(:,2);
channel3RawData = app.rawChannelData(:,3);
channel4RawData = app.rawChannelData(:,4);
channel5RawData = app.rawChannelData(:,5);

plot(app.RawDataChannel1,app.nSeq,channel1RawData);
plot(app.RawDataChannel2,app.nSeq,channel2RawData);
plot(app.RawDataChannel3,app.nSeq,channel3RawData);
plot(app.RawDataChannel4,app.nSeq,channel4RawData);
plot(app.RawDataChannel5,app.nSeq,channel5RawData);


if (width(app.SettingsCounter) == 1)
    app.SettingsUsed = app.SettingsUsed + ("{Import: " + pathToFile + "}");
end

app.ConversionButton.Enable = 'on';
app.ConverttomVButton.Enable = 'on';
app.ImageConversion.Enable = 'on';
app.mVDataMatrix = ((app.rawChannelData ./ (2^16)) - (0.5)) * 3;

        end

        % Callback function
        function TextAreaValueChanged(app, event)
%             value = app.DocumentationOutliers.Value;

        end

        % Button pushed function: ConverttomVButton
        function ConverttomVButtonPushed(app, event)
                
            app.UITable_2.Data = app.mVDataMatrix;

            channel1MvData = app.mVDataMatrix(:,1);
            channel2MvData = app.mVDataMatrix(:,2);
            channel3MvData = app.mVDataMatrix(:,3);
            channel4MvData = app.mVDataMatrix(:,4);
            channel5MvData = app.mVDataMatrix(:,5);

            plot(app.MvDataChannel1,app.nSeq,channel1MvData);
            plot(app.MvDataChannel2,app.nSeq,channel2MvData);
            plot(app.MvDataChannel3,app.nSeq,channel3MvData);
            plot(app.MvDataChannel4,app.nSeq,channel4MvData);
            plot(app.MvDataChannel5,app.nSeq,channel5MvData);

            app.mVDATALabel.Visible = 'on';
            
            if (width(app.SettingsCounter) == 2)
                app.SettingsUsed = app.SettingsUsed + "{MV}";
            end

            app.FastFourrierButton.Enable = 'on';
            app.FFTButton.Enable = 'on';
            app.ImageFastFourrier.Enable = 'on';

        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
            
            app.fs = 1000;
            L = length(app.nSeq);
            app.f = app.fs*(0:(L/2))/L;
                 
            app.FFTDataMatrix = fft(app.mVDataMatrix);
            
            app.Channel1FFTData = app.FFTDataMatrix(:,1);
            app.Channel2FFTData = app.FFTDataMatrix(:,2);
            app.Channel3FFTData = app.FFTDataMatrix(:,3);
            app.Channel4FFTData = app.FFTDataMatrix(:,4);
            app.Channel5FFTData = app.FFTDataMatrix(:,5);

            app.Channel1FFTData = abs(app.Channel1FFTData/L);
            app.Channel2FFTData = abs(app.Channel2FFTData/L);
            app.Channel3FFTData = abs(app.Channel3FFTData/L);
            app.Channel4FFTData = abs(app.Channel4FFTData/L);
            app.Channel5FFTData = abs(app.Channel5FFTData/L);
     
            app.Channel1FFTData = app.Channel1FFTData(1:L/2+1);
            app.Channel1FFTData(2:end-1) = 2*app.Channel1FFTData(2:end-1);
            app.Channel2FFTData = app.Channel2FFTData(1:L/2+1);
            app.Channel2FFTData(2:end-1) = 2*app.Channel2FFTData(2:end-1);
            app.Channel3FFTData = app.Channel3FFTData(1:L/2+1);
            app.Channel3FFTData(2:end-1) = 2*app.Channel3FFTData(2:end-1);
            app.Channel4FFTData = app.Channel4FFTData(1:L/2+1);
            app.Channel4FFTData(2:end-1) = 2*app.Channel4FFTData(2:end-1);
            app.Channel5FFTData = app.Channel5FFTData(1:L/2+1);
            app.Channel5FFTData(2:end-1) = 2*app.Channel5FFTData(2:end-1);
                      
            plot(app.FFTDataChannel1,app.f,app.Channel1FFTData);
            plot(app.FFTDataChannel2,app.f,app.Channel2FFTData);
            plot(app.FFTDataChannel3,app.f,app.Channel3FFTData);
            plot(app.FFTDataChannel4,app.f,app.Channel4FFTData);
            plot(app.FFTDataChannel5,app.f,app.Channel5FFTData);

            if (width(app.SettingsCounter) == 3)
                app.SettingsUsed = app.SettingsUsed + "{FFT}";
            end
         
            app.OutlierremovalButton.Enable = 'on';
            app.OutlierDeviationOptionDropdown.Enable = 'on';
            app.REMOVEOUTLIERButton.Enable = 'on';
            app.ImageOutlier.Enable = 'on';

        end

        % Button pushed function: REMOVEOUTLIERButton
        function REMOVEOUTLIERButtonPushed(app, event)
            % CLEAR ALLE PLOTS
            cla(app.OutlierDataChannel1);
            cla(app.OutlierDataChannel2);
            cla(app.OutlierDataChannel3); 
            cla(app.OutlierDataChannel4); 
            cla(app.OutlierDataChannel5); 


            if(app.removeOutlierOption == "grubbs" && (app.thresholdFactor ~= 1))
                uialert(app.UIFigure,'When choosing grubbs option thresholdFactor must be between 0 and 1','An error occured');
            elseif(app.removeOutlierOption == "gesd" && (app.thresholdFactor ~= 1))
                uialert(app.UIFigure,'When choosing gesd option thresholdFactor must be between 0 and 1','An error occured');
            else
                Outlier1Channel1 = filloutliers(app.Channel1FFTData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
                Outlier1Channel2 = filloutliers(app.Channel2FFTData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
                Outlier1Channel3 = filloutliers(app.Channel3FFTData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
                Outlier1Channel4 = filloutliers(app.Channel4FFTData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
                Outlier1Channel5 = filloutliers(app.Channel5FFTData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
    
                hold(app.OutlierDataChannel1,'on');
                plot(app.OutlierDataChannel1,app.f,app.Channel1FFTData);
                plot(app.OutlierDataChannel1,app.f,Outlier1Channel1,'r-.');
                hold(app.OutlierDataChannel1,'off');
                hold(app.OutlierDataChannel2,'on');
                plot(app.OutlierDataChannel2,app.f,app.Channel2FFTData);
                plot(app.OutlierDataChannel2,app.f,Outlier1Channel2,'r-.');
                hold(app.OutlierDataChannel2,'off');
                hold(app.OutlierDataChannel3,'on');
                plot(app.OutlierDataChannel3,app.f,app.Channel3FFTData);
                plot(app.OutlierDataChannel3,app.f,Outlier1Channel3,'r-.');
                hold(app.OutlierDataChannel3,'off');
                hold(app.OutlierDataChannel4,'on');
                plot(app.OutlierDataChannel4,app.f,app.Channel4FFTData);
                plot(app.OutlierDataChannel4,app.f,Outlier1Channel4,'r-.');
                hold(app.OutlierDataChannel4,'off');
                hold(app.OutlierDataChannel5,'on');
                plot(app.OutlierDataChannel5,app.f,app.Channel5FFTData);
                plot(app.OutlierDataChannel5,app.f,Outlier1Channel5,'r-.');
                hold(app.OutlierDataChannel5,'off');

                if (height(app.SettingsUsed) == 4)
                    app.SettingsUsed = app.SettingsUsed + ("{Outliers: " + "{" + app.OutlierOptionDropDown.Value + "} {" + app.RemoveOutlierOptionDropdown.Value + "} {" + app.FillOutlierOptionDropdown.Value + "}}");
                end
    
                app.TrackAButton.Enable = 'on';
                app.TrackBButton.Enable = 'on';
                app.ImageA.Enable = 'on';
                app.ImageB.Enable = 'on';
                app.FilterOrderDropdown.Enable = 'on';
                app.FilterTypeDropdown.Enable = 'on';
                app.ApplyfilterButton.Enable = 'on';
                app.PROCESSINGTRACKBButton.Enable = 'on';
                app.TrackA_FC1.Enable = 'on';
            end

        end

        % Value changed function: OutlierDeviationOptionDropdown
        function OutlierDeviationOptionDropdownValueChanged(app, event)
            value = app.OutlierDeviationOptionDropdown.Value;
            app.thresholdFactor = str2num(value);
        end

        % Button pushed function: PROCESSINGTRACKBButton
        function PROCESSINGTRACKBButtonPushed(app, event)
            Fs = 100;
            Framelength = 10;
            %ctr1 = (1:1:size(app.rawChannelData,1));
            ch1 = app.mVDataMatrix(:,1); 
            movrmsWin = dsp.MovingRMS(20);
            %%movrmsWin_overlap = dsp.MovingRMS(20,15); %%overlapping werkt niet , werkende versie is: dsp.MovingRMS(15);
            movrmsWin_overlap = dsp.MovingRMS(15);
            movrmsExp = dsp.MovingRMS('Method','Exponential weighting','ForgettingFactor',0.995);

            scope  = timescope('SampleRate',[Fs,Fs,Fs/(20-15),Fs],...
                'TimeSpanOverrunAction','Scroll',...
                'TimeSpanSource','Property',...
                'TimeSpan',500,...
                'ShowGrid',true,...
                'YLimits',[-10.0 10]);
            title = 'Moving RMS';
            scope.Title = title;
            scope.ChannelNames = {'Original Signal',...
            'Sliding window of 20 samples with default overlap',...
            'Sliding window of 20 samples with an overlap of 15 samples',...
            'Exponential weighting with forgetting factor of 0.995'};
            %count = 1;
            Vect = [1/8 1/2 1 2 3 4];
            for index = 1:length(Vect)
                V = Vect(index);
                for i = 1:size(ch1,1)
                    x = V + 0.1 * randn(Framelength,1);
                    y1 = movrmsWin(ch1(i,1));
                    y2 = movrmsWin_overlap(ch1(i,1));
                    y3 = movrmsExp(ch1(i,1));
                    scope(x,y1,y2,y3);
                end
            end
            show(scope);
                

        end

        % Button pushed function: ApplyfilterButton
        function ApplyfilterButtonPushed(app, event)

            % CLEAR ALLE PLOTS
            cla(app.TrackAchannel1);
            cla(app.TrackAchannel2);
            cla(app.TrackAchannel3); 
            cla(app.TrackAchannel4); 
            cla(app.TrackAchannel5); 

            nyF = app.fs/2;

            channel1MvData = app.mVDataMatrix(:,1);
            channel2MvData = app.mVDataMatrix(:,2);
            channel3MvData = app.mVDataMatrix(:,3);
            channel4MvData = app.mVDataMatrix(:,4);
            channel5MvData = app.mVDataMatrix(:,5);

            if(isequal(num2str(app.FilterType),'hp') || isequal(num2str(app.FilterType),'lp'))
                    ylp =  filter1(num2str(app.FilterType),channel1MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order', uint16(app.FilterOrder));
                    ylp2 = filter1(num2str(app.FilterType),channel2MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order',uint16(app.FilterOrder));
                    ylp3 = filter1(num2str(app.FilterType),channel3MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order',uint16(app.FilterOrder));
                    ylp4 = filter1(num2str(app.FilterType),channel4MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order',uint16(app.FilterOrder));
                    ylp5 = filter1(num2str(app.FilterType),channel5MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order',uint16(app.FilterOrder));
            elseif(isequal(num2str(app.FilterType),'bp') || isequal(num2str(app.FilterType),'bs'))
                    ylp =  filter1(num2str(app.FilterType),channel1MvData,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order', uint16(app.FilterOrder));
                    ylp2 = filter1(num2str(app.FilterType),channel2MvData,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order',uint16(app.FilterOrder));
                    ylp3 = filter1(num2str(app.FilterType),channel3MvData,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order',uint16(app.FilterOrder));
                    ylp4 = filter1(num2str(app.FilterType),channel4MvData,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order',uint16(app.FilterOrder));
                    ylp5 = filter1(num2str(app.FilterType),channel5MvData,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order',uint16(app.FilterOrder));
            end

%             FULL WAVE RECITIFICATION
                rectYlp1 = abs(ylp);
                rectYlp2 = abs(ylp2);
                rectYlp3 = abs(ylp3);
                rectYlp4 = abs(ylp4);
                rectYlp5 = abs(ylp5);
                
%                 PLOTTING DATA
            hold(app.TrackAchannel1,'on');
            plot(app.TrackAchannel1, abs(channel1MvData));
            plot(app.TrackAchannel1, rectYlp1,'r');
            hold(app.TrackAchannel1,'off');

            hold(app.TrackAchannel2,'on');
            plot(app.TrackAchannel2, abs(channel2MvData));
            plot(app.TrackAchannel2, rectYlp2,'r');
            hold(app.TrackAchannel2,'off');
           
            hold(app.TrackAchannel3,'on');
            plot(app.TrackAchannel3, abs(channel3MvData));
            plot(app.TrackAchannel3, rectYlp3,'r');
            hold(app.TrackAchannel3,'off');

            hold(app.TrackAchannel4,'on');
            plot(app.TrackAchannel4, abs(channel4MvData));
            plot(app.TrackAchannel4, rectYlp4,'r');
            hold(app.TrackAchannel4,'off');

            hold(app.TrackAchannel5,'on');
            plot(app.TrackAchannel5, abs(channel5MvData));
            plot(app.TrackAchannel5, rectYlp5,'r');
            hold(app.TrackAchannel5,'off');

            if (height(app.SettingsUsed) == 4)
             %   app.SettingsUsed = [app.SettingsUsed; "Processing track A:"; ("\tFilter type: " + app.FilterType); ("\tFilter order: " + app.FilterOrder) ];
            end
            
            app.TrackBButton.Enable = 'off';
            app.ImageB.Enable = 'off';

        end

        % Value changed function: FilterTypeDropdown
        function FilterTypeDropdownValueChanged(app, event)
            value = app.FilterTypeDropdown.Value;
            if value == "High-pass"
                app.TrackA_FC2.Enable = 'off';
%                 app.TrackA_FC1.Limits = [0 1];
                app.FilterType = 'hp';
            elseif value == "Low-pass"
                app.TrackA_FC2.Enable = 'off';
%                 app.TrackA_FC1.Limits = [0 1];
                app.FilterType = 'lp';
            elseif value == "Band-pass"
                app.TrackA_FC2.Enable = 'on';
                app.TrackA_FC1.Value = 0;
                app.TrackA_FC2.Value = 1;
%                 app.TrackA_FC1.Limits = [0 round(app.TrackA_FC2.Value)];
%                 app.TrackA_FC2.Limits = [round(app.TrackA_FC1.Value) 100];
                app.FilterType = 'bp';
            elseif value == "Bandstop"
                app.TrackA_FC2.Enable = 'on';
                app.TrackA_FC1.Value = 0;
                app.TrackA_FC2.Value = 1;
%                 app.TrackA_FC1.Limits = [0 round(app.TrackA_FC2.Value)];
%                 app.TrackA_FC2.Limits = [round(app.TrackA_FC1.Value) 100];
                app.FilterType = 'bs';
            end

        end

        % Value changed function: FilterOrderDropdown
        function FilterOrderDropdownValueChanged(app, event)
            value = app.FilterOrderDropdown.Value;
            if value == "1"
                app.FilterOrder = 1;
            elseif value == "2"
                app.FilterOrder = 2;
            elseif value == "3"
                app.FilterOrder = 3;
            elseif value == "4"
                app.FilterOrder = 4;
            elseif value == "5"
                app.FilterOrder = 5;
            end
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
            % CURRENTLY MV DATA.............             
            settingsHeader = ["===" "start" "settings" "header" "==="; app.SettingsUsed "" "" "" ""; "===" "end" "settings" "header" "==="];
            labels = ["CH1" "CH2" "CH3" "CH4" "CH5"];
            export = [settingsHeader; labels; app.mVDataMatrix];
            
            writematrix(export,'export.txt','Delimiter','\t')  
            type export.txt
            
            %second file for steps and settings
            writematrix(app.SettingsUsed,'settings.txt','Delimiter','\t') ; 
            type settings.txt;
        end

        % Value changed function: FillOutlierOptionDropdown
        function FillOutlierOptionDropdownValueChanged(app, event)
            value = app.FillOutlierOptionDropdown.Value;
            app.fillOutlierOption = value;
        end

        % Value changed function: RemoveOutlierOptionDropdown
        function RemoveOutlierOptionDropdownValueChanged(app, event)
            value = app.RemoveOutlierOptionDropdown.Value;
            app.removeOutlierOption = value;
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if (app.ImportdataButton.Value == true)
                ResetButtonColor(app);
                app.ImportdataButton.FontColor = '0.86,0.65,0.30';
                app.ImportdataButton.BackgroundColor = '0.13,0.33,0.46';
                app.TabGroup.SelectedTab = app.RAWDATATab;
            end
            if (app.ConversionButton.Value == true)
                ResetButtonColor(app);
                app.ConversionButton.FontColor = '0.86,0.65,0.30';
                app.ConversionButton.BackgroundColor = '0.13,0.33,0.46';
                app.TabGroup.SelectedTab = app.mVDATATab;
            end
            if (app.FastFourrierButton.Value == true)
                ResetButtonColor(app);
                app.FastFourrierButton.FontColor = '0.86,0.65,0.30';
                app.FastFourrierButton.BackgroundColor = '0.13,0.33,0.46';
                app.TabGroup.SelectedTab = app.FFTANALYSISTab;
            end
            if (app.OutlierremovalButton.Value == true)
                ResetButtonColor(app);
                app.OutlierremovalButton.FontColor = '0.86,0.65,0.30';
                app.OutlierremovalButton.BackgroundColor = '0.13,0.33,0.46';
                
                %load documentation
                docOut = sprintf(['Percentile thresholds, specified as a two-element row vector whose elements are in the interval ' ...
                    '[0,100]. The first element indicates the lower percentile threshold and the second element indicates the upper ' ...
                    'percentile threshold. For example, a threshold of [10 90] defines outliers as points below the 10th percentile ' ...
                    'and above the 90th percentile. The first element of threshold must be less than the second element. \n\nMethod ' ...
                    'for detecting outliers, specified as one of the following:\n\nMedian:\t	' ...
                    'Outliers are defined as elements more than three scaled MAD from the median. The scaled MAD is' ...
                    '  defined as c*median(abs(A-median(A))), where c=-1/(sqrt(2)*erfcinv(3/2)). \n\nMean:\t	' ...
                    'Outliers are defined as elements more than three standard deviations from the mean. This method ' ...
                    'is faster but less robust than median. \n\nQuartiles:\t	Outliers are defined as elements more ' ...
                    'than 1.5 interquartile ranges above the upper quartile (75 percent) or below the lower quartile ' ...
                    '(25 percent). This method is useful when the data in A is not normally distributed. \n\nGrubbs: ' ...
                    '\t	Outliers are detected using Grubbs’s test, which removes one outlier per iteration based on ' ...
                    'hypothesis testing. This method assumes that the data in A is normally distributed. \n\nGesd: \t	' ...
                    'Outliers are detected using the generalized extreme Studentized deviate test for outliers. This ' ...
                    'iterative method is similar to grubbs, but can perform better when there are multiple outliers masking each other. \n\n' ...
                    'Fill method for replacing outliers, specified as a numeric scalar or one of the following: \n\n' ...
                    'Center: \t	Fills with the center value determined by findmethod\n\n' ...
                    'Clip: \t	Fills with the lower threshold value for elements smaller than the lower threshold determined by findmethod. ' ...
                    'Fills with the upper threshold value for elements larger than the upper threshold determined by findmethod\n\n' ...
                    'Previous: \t	Fills with the previous non-outlier value\n\n' ...
                    'Next: \t	Fills with the next non-outlier value\n\n' ...
                    'Nearest: \t Fills with the nearest non-outlier value\n\n' ...
                    'Linear: \t	Fills using linear interpolation of neighboring, non-outlier values\n\n' ...
                    'Spline: \t	Fills using piecewise cubic spline interpolation\n\n' ...
                    'Pchip: \t	Fills using shape-preserving piecewise cubic spline interpolation\n\n' ...
                    'Makima: \t	modified Akima cubic Hermite interpolation (numeric, duration, and datetime data types only) \n\n']);
                app.DocumentationOutliers.Value = docOut;
                app.TabGroup.SelectedTab = app.REMOVEOUTLIERTab;
            end
            
            if (app.TrackAButton.Value == true)
                ResetButtonColor(app);
                app.TrackAButton.FontColor = '0.86,0.65,0.30';
                app.TrackAButton.BackgroundColor = '0.13,0.33,0.46';
                
                docTrack = sprintf(['Filters 1D signal yusing a specified filtertype and cutoff ' ...
                    'frequency Fc. For high-pass or low-pass filters Fc must be a scalar. For band-pass ' ...
                    'and band-stop filters Fc must be a two-element array. \n\n' ...
                    'The filtertype can be: \n\n' ...
                    '\t high-pass with scalar cutoff frequency Fc\n' ...
                    '\t low-pass with scalar cutoff frequency Fc\n' ...
                    '\t band-pass with two-element cutoff frequencies Fc\n' ...
                    '\t band-stop with two-element cutoff frequencies Fc \n']);
                app.DocumentationTrackA.Value = docTrack;
                app.TabGroup.SelectedTab = app.PROCESSINGTRACKATab;
            end
            if (app.TrackBButton.Value == true)
                ResetButtonColor(app);
                app.TrackBButton.FontColor = '0.86,0.65,0.30';
                app.TrackBButton.BackgroundColor = '0.13,0.33,0.46';
                app.TabGroup.SelectedTab = app.PROCESSINGTRACKBTab;
            end
            
            if (app.MVCNormalisationButton.Value == true)
                app.MVCNormalisationButton.FontColor = '0.86,0.65,0.30';
                app.ImportdataButton.BackgroundColor = '0.13,0.33,0.46';
                app.TabGroup.SelectedTab = app.MVCNORMALISATIONTab;
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                delete(app)
            end
        end

        % Image clicked function: ExitImage
        function ExitImageClicked(app, event)
            UIFigureCloseRequest(app);
        end

        % Value changed function: TrackA_FC2
        function TrackA_FC2ValueChanged(app, event)
            value = app.TrackA_FC2.Value;
            
        end

        % Value changed function: TrackA_FC1
        function TrackA_FC1ValueChanged(app, event)
            value = app.TrackA_FC1.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RAWDATATab = uitab(app.TabGroup);
            app.RAWDATATab.Title = 'RAW DATA';
            app.TabGroup2 = uitabgroup(app.RAWDATATab);
            app.CH1Tab = uitab(app.TabGroup2);
            app.CH1Tab.Title = 'CH1';
            app.RawDataChannel1 = uiaxes(app.CH1Tab);
            title(app.RawDataChannel1, 'Data - Channel 1')
            xlabel(app.RawDataChannel1, 'nSeq')
            ylabel(app.RawDataChannel1, 'Sensor value')
            zlabel(app.RawDataChannel1, 'Z')
            app.CH2Tab = uitab(app.TabGroup2);
            app.CH2Tab.Title = 'CH2';
            app.RawDataChannel2 = uiaxes(app.CH2Tab);
            title(app.RawDataChannel2, 'Data - Channel 2')
            xlabel(app.RawDataChannel2, 'nSeq')
            ylabel(app.RawDataChannel2, 'Sensor value')
            zlabel(app.RawDataChannel2, 'Z')
            app.CH3Tab = uitab(app.TabGroup2);
            app.CH3Tab.Title = 'CH3';
            app.RawDataChannel3 = uiaxes(app.CH3Tab);
            title(app.RawDataChannel3, 'Data - Channel 3')
            xlabel(app.RawDataChannel3, 'nSeq')
            ylabel(app.RawDataChannel3, 'Sensor value')
            zlabel(app.RawDataChannel3, 'Z')
            app.CH4Tab = uitab(app.TabGroup2);
            app.CH4Tab.Title = 'CH4';
            app.RawDataChannel4 = uiaxes(app.CH4Tab);
            title(app.RawDataChannel4, 'Data - Channel 4')
            xlabel(app.RawDataChannel4, 'nSeq')
            ylabel(app.RawDataChannel4, 'Sensor value')
            zlabel(app.RawDataChannel4, 'Z')
            app.CH5Tab = uitab(app.TabGroup2);
            app.CH5Tab.Title = 'CH5';
            app.RawDataChannel5 = uiaxes(app.CH5Tab);
            title(app.RawDataChannel5, 'Data - Channel 5')
            xlabel(app.RawDataChannel5, 'nSeq')
            ylabel(app.RawDataChannel5, 'Sensor value')
            zlabel(app.RawDataChannel5, 'Z')
            app.YourdataLabel = uilabel(app.RAWDATATab);
            app.YourdataLabel.Visible = 'off';
            app.YourdataLabel.Text = 'Your data';
            app.UITable = uitable(app.RAWDATATab);
            app.UITable.ColumnName = {'nSeq'; 'DI'; 'CH1'; 'CH2'; 'CH3'; 'CH4'; 'CH5'};
            app.UITable.RowName = {};
            app.SelectfileButton = uibutton(app.RAWDATATab, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.mVDATATab = uitab(app.TabGroup);
            app.mVDATATab.Title = 'mV DATA';
            app.TabGroup2_2 = uitabgroup(app.mVDATATab);
            app.CH1Tab_2 = uitab(app.TabGroup2_2);
            app.CH1Tab_2.Title = 'CH1';
            app.MvDataChannel1 = uiaxes(app.CH1Tab_2);
            title(app.MvDataChannel1, 'Data - Channel 1')
            xlabel(app.MvDataChannel1, 'nSeq')
            ylabel(app.MvDataChannel1, 'mV value')
            zlabel(app.MvDataChannel1, 'Z')
            app.CH2Tab_2 = uitab(app.TabGroup2_2);
            app.CH2Tab_2.Title = 'CH2';
            app.MvDataChannel2 = uiaxes(app.CH2Tab_2);
            title(app.MvDataChannel2, 'Data - Channel 2')
            xlabel(app.MvDataChannel2, 'nSeq')
            ylabel(app.MvDataChannel2, 'mV value')
            zlabel(app.MvDataChannel2, 'Z')
            app.CH3Tab_2 = uitab(app.TabGroup2_2);
            app.CH3Tab_2.Title = 'CH3';
            app.MvDataChannel3 = uiaxes(app.CH3Tab_2);
            title(app.MvDataChannel3, 'Data - Channel 3')
            xlabel(app.MvDataChannel3, 'nSeq')
            ylabel(app.MvDataChannel3, 'mV value')
            zlabel(app.MvDataChannel3, 'Z')
            app.CH4Tab_2 = uitab(app.TabGroup2_2);
            app.CH4Tab_2.Title = 'CH4';
            app.MvDataChannel4 = uiaxes(app.CH4Tab_2);
            title(app.MvDataChannel4, 'Data - Channel 4')
            xlabel(app.MvDataChannel4, 'nSeq')
            ylabel(app.MvDataChannel4, 'mV value')
            zlabel(app.MvDataChannel4, 'Z')
            app.CH5Tab_2 = uitab(app.TabGroup2_2);
            app.CH5Tab_2.Title = 'CH5';
            app.MvDataChannel5 = uiaxes(app.CH5Tab_2);
            title(app.MvDataChannel5, 'Data - Channel 5')
            xlabel(app.MvDataChannel5, 'nSeq')
            ylabel(app.MvDataChannel5, 'mV value')
            zlabel(app.MvDataChannel5, 'Z')
            app.UITable_2 = uitable(app.mVDATATab);
            app.UITable_2.ColumnName = {'CH1'; 'CH2'; 'CH3'; 'CH4'; 'CH5'};
            app.UITable_2.RowName = {};
            app.mVDATALabel = uilabel(app.mVDATATab);
            app.mVDATALabel.Visible = 'off';
            app.mVDATALabel.Text = 'mV DATA';
            app.ConverttomVButton = uibutton(app.mVDATATab, 'push');
            app.ConverttomVButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttomVButtonPushed, true);
            app.ConverttomVButton.Enable = 'off';
            app.ConverttomVButton.Text = 'Convert to mV';
            app.FFTANALYSISTab = uitab(app.TabGroup);
            app.FFTANALYSISTab.Title = 'FFT ANALYSIS';
            app.TabGroup2_3 = uitabgroup(app.FFTANALYSISTab);
            app.CH1Tab_3 = uitab(app.TabGroup2_3);
            app.CH1Tab_3.Title = 'CH1';
            app.FFTDataChannel1 = uiaxes(app.CH1Tab_3);
            title(app.FFTDataChannel1, 'Data - Channel 1')
            xlabel(app.FFTDataChannel1, 'Hz')
            ylabel(app.FFTDataChannel1, {'intensity'; ''})
            zlabel(app.FFTDataChannel1, 'Z')
            app.CH2Tab_3 = uitab(app.TabGroup2_3);
            app.CH2Tab_3.Title = 'CH2';
            app.FFTDataChannel2 = uiaxes(app.CH2Tab_3);
            title(app.FFTDataChannel2, 'Data - Channel 2')
            xlabel(app.FFTDataChannel2, 'Hz')
            ylabel(app.FFTDataChannel2, 'Intensity')
            zlabel(app.FFTDataChannel2, 'Z')
            app.CH3Tab_3 = uitab(app.TabGroup2_3);
            app.CH3Tab_3.Title = 'CH3';
            app.FFTDataChannel3 = uiaxes(app.CH3Tab_3);
            title(app.FFTDataChannel3, 'Data - Channel 3')
            xlabel(app.FFTDataChannel3, 'Hz')
            ylabel(app.FFTDataChannel3, 'Intensity')
            zlabel(app.FFTDataChannel3, 'Z')
            app.CH4Tab_6 = uitab(app.TabGroup2_3);
            app.CH4Tab_6.Title = 'CH4';
            app.FFTDataChannel4 = uiaxes(app.CH4Tab_6);
            title(app.FFTDataChannel4, 'Data - Channel 4')
            xlabel(app.FFTDataChannel4, 'Hz')
            ylabel(app.FFTDataChannel4, 'Intensity')
            zlabel(app.FFTDataChannel4, 'Z')
            app.CH5Tab_3 = uitab(app.TabGroup2_3);
            app.CH5Tab_3.Title = 'CH5';
            app.FFTDataChannel5 = uiaxes(app.CH5Tab_3);
            title(app.FFTDataChannel5, 'Data - Channel 5')
            xlabel(app.FFTDataChannel5, 'Hz')
            ylabel(app.FFTDataChannel5, 'Intensity')
            zlabel(app.FFTDataChannel5, 'Z')
            app.FFTButton = uibutton(app.FFTANALYSISTab, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Enable = 'off';
            app.FFTButton.Text = 'FFT';
            app.REMOVEOUTLIERTab = uitab(app.TabGroup);
            app.REMOVEOUTLIERTab.Title = 'REMOVE OUTLIER';
            app.TabGroup2_4 = uitabgroup(app.REMOVEOUTLIERTab);
            app.CH1Tab_4 = uitab(app.TabGroup2_4);
            app.CH1Tab_4.Title = 'CH1';
            app.OutlierDataChannel1 = uiaxes(app.CH1Tab_4);
            title(app.OutlierDataChannel1, 'Title')
            xlabel(app.OutlierDataChannel1, 'X')
            ylabel(app.OutlierDataChannel1, 'Y')
            zlabel(app.OutlierDataChannel1, 'Z')
            app.CH2Tab_4 = uitab(app.TabGroup2_4);
            app.CH2Tab_4.Title = 'CH2';
            app.OutlierDataChannel2 = uiaxes(app.CH2Tab_4);
            title(app.OutlierDataChannel2, 'Title')
            xlabel(app.OutlierDataChannel2, 'X')
            ylabel(app.OutlierDataChannel2, 'Y')
            zlabel(app.OutlierDataChannel2, 'Z')
            app.CH3Tab_4 = uitab(app.TabGroup2_4);
            app.CH3Tab_4.Title = 'CH3';
            app.OutlierDataChannel3 = uiaxes(app.CH3Tab_4);
            title(app.OutlierDataChannel3, 'Title')
            xlabel(app.OutlierDataChannel3, 'X')
            ylabel(app.OutlierDataChannel3, 'Y')
            zlabel(app.OutlierDataChannel3, 'Z')
            app.CH4Tab_4 = uitab(app.TabGroup2_4);
            app.CH4Tab_4.Title = 'CH4';
            app.OutlierDataChannel4 = uiaxes(app.CH4Tab_4);
            title(app.OutlierDataChannel4, 'Title')
            xlabel(app.OutlierDataChannel4, 'X')
            ylabel(app.OutlierDataChannel4, 'Y')
            zlabel(app.OutlierDataChannel4, 'Z')
            app.CH5Tab_4 = uitab(app.TabGroup2_4);
            app.CH5Tab_4.Title = 'CH5';
            app.OutlierDataChannel5 = uiaxes(app.CH5Tab_4);
            title(app.OutlierDataChannel5, 'Title')
            xlabel(app.OutlierDataChannel5, 'X')
            ylabel(app.OutlierDataChannel5, 'Y')
            zlabel(app.OutlierDataChannel5, 'Z')
            app.Label_3 = uilabel(app.REMOVEOUTLIERTab);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.REMOVEOUTLIERTab);
            app.Label_4.Text = '';
            app.StandarddeviationLabel = uilabel(app.REMOVEOUTLIERTab);
            app.StandarddeviationLabel.Text = 'Standard deviation:';
            app.OutlieroptionsLabel = uilabel(app.REMOVEOUTLIERTab);
            app.OutlieroptionsLabel.Text = 'Outlier options';
            app.OutlierDeviationOptionDropdown = uidropdown(app.REMOVEOUTLIERTab);
            app.OutlierDeviationOptionDropdown.Items = {'1', '2', '3'};
            app.OutlierDeviationOptionDropdown.ValueChangedFcn = createCallbackFcn(app, @OutlierDeviationOptionDropdownValueChanged, true);
            app.OutlierDeviationOptionDropdown.Value = '1';
            app.RemoveOutlierOptionDropdown = uidropdown(app.REMOVEOUTLIERTab);
            app.RemoveOutlierOptionDropdown.Items = {'median', 'mean', 'quartiles', 'grubbs', 'gesd'};
            app.RemoveOutlierOptionDropdown.ValueChangedFcn = createCallbackFcn(app, @RemoveOutlierOptionDropdownValueChanged, true);
            app.RemoveOutlierOptionDropdown.Value = 'median';
            app.FillOutlierOptionDropdown = uidropdown(app.REMOVEOUTLIERTab);
            app.FillOutlierOptionDropdown.Items = {'center', 'clip', 'previous', 'next', 'nearest', 'linear', 'spline', 'pchip', 'makima'};
            app.FillOutlierOptionDropdown.ValueChangedFcn = createCallbackFcn(app, @FillOutlierOptionDropdownValueChanged, true);
            app.FillOutlierOptionDropdown.Value = 'center';
            app.MethodfordetectingoutliersLabel = uilabel(app.REMOVEOUTLIERTab);
            app.MethodfordetectingoutliersLabel.Text = 'Method for detecting outliers:';
            app.FillmethodLabel = uilabel(app.REMOVEOUTLIERTab);
            app.FillmethodLabel.Text = 'Fill method:';
            app.REMOVEOUTLIERButton = uibutton(app.REMOVEOUTLIERTab, 'push');
            app.REMOVEOUTLIERButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVEOUTLIERButtonPushed, true);
            app.REMOVEOUTLIERButton.Text = 'REMOVE OUTLIER';
            app.DocumentationTextAreaLabel = uilabel(app.REMOVEOUTLIERTab);
            app.DocumentationTextAreaLabel.Text = 'Documentation';
            app.DocumentationOutliers = uitextarea(app.REMOVEOUTLIERTab);
            app.DocumentationOutliers.Editable = 'off';
            app.PROCESSINGTRACKATab = uitab(app.TabGroup);
            app.PROCESSINGTRACKATab.Title = 'PROCESSING TRACK A';
            app.TabGroup2_5 = uitabgroup(app.PROCESSINGTRACKATab);
            app.CH1Tab_5 = uitab(app.TabGroup2_5);
            app.CH1Tab_5.Title = 'CH1';
            app.TrackAchannel1 = uiaxes(app.CH1Tab_5);
            title(app.TrackAchannel1, 'Data - Channel 1')
            xlabel(app.TrackAchannel1, 'X')
            ylabel(app.TrackAchannel1, 'Y')
            zlabel(app.TrackAchannel1, 'Z')
            app.CH2Tab_5 = uitab(app.TabGroup2_5);
            app.CH2Tab_5.Title = 'CH2';
            app.TrackAchannel2 = uiaxes(app.CH2Tab_5);
            title(app.TrackAchannel2, 'Data - Channel 2')
            xlabel(app.TrackAchannel2, 'X')
            ylabel(app.TrackAchannel2, 'Y')
            zlabel(app.TrackAchannel2, 'Z')
            app.CH3Tab_5 = uitab(app.TabGroup2_5);
            app.CH3Tab_5.Title = 'CH3';
            app.TrackAchannel3 = uiaxes(app.CH3Tab_5);
            title(app.TrackAchannel3, 'Data - Channel 3')
            xlabel(app.TrackAchannel3, 'X')
            ylabel(app.TrackAchannel3, 'Y')
            zlabel(app.TrackAchannel3, 'Z')
            app.CH4Tab_5 = uitab(app.TabGroup2_5);
            app.CH4Tab_5.Title = 'CH4';
            app.TrackAchannel4 = uiaxes(app.CH4Tab_5);
            title(app.TrackAchannel4, 'Data - Channel 4')
            xlabel(app.TrackAchannel4, 'X')
            ylabel(app.TrackAchannel4, 'Y')
            zlabel(app.TrackAchannel4, 'Z')
            app.CH5Tab_5 = uitab(app.TabGroup2_5);
            app.CH5Tab_5.Title = 'CH5';
            app.TrackAchannel5 = uiaxes(app.CH5Tab_5);
            title(app.TrackAchannel5, 'Data - Channel 5')
            xlabel(app.TrackAchannel5, 'X')
            ylabel(app.TrackAchannel5, 'Y')
            zlabel(app.TrackAchannel5, 'Z')
            app.Label_5 = uilabel(app.PROCESSINGTRACKATab);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.PROCESSINGTRACKATab);
            app.Label_6.Text = '';
            app.FilteroptionsLabel = uilabel(app.PROCESSINGTRACKATab);
            app.FilteroptionsLabel.Text = 'Filter options';
            app.FiltertypeLabel = uilabel(app.PROCESSINGTRACKATab);
            app.FiltertypeLabel.Text = 'Filter type:';
            app.ApplyfilterButton = uibutton(app.PROCESSINGTRACKATab, 'push');
            app.ApplyfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyfilterButtonPushed, true);
            app.ApplyfilterButton.Text = 'Apply filter';
            app.FilterTypeDropdown = uidropdown(app.PROCESSINGTRACKATab);
            app.FilterTypeDropdown.Items = {'High-pass', 'Low-pass', 'Band-pass', 'Bandstop'};
            app.FilterTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropdownValueChanged, true);
            app.FilterTypeDropdown.Placeholder = 'Select filter';
            app.FilterTypeDropdown.Value = 'High-pass';
            app.FilterOrderDropdown = uidropdown(app.PROCESSINGTRACKATab);
            app.FilterOrderDropdown.Items = {'1', '2', '3', '4', '5'};
            app.FilterOrderDropdown.ValueChangedFcn = createCallbackFcn(app, @FilterOrderDropdownValueChanged, true);
            app.FilterOrderDropdown.Value = '1';
            app.FilterorderLabel = uilabel(app.PROCESSINGTRACKATab);
            app.FilterorderLabel.Text = 'Filter order:';
            app.TrackA_FC2 = uieditfield(app.PROCESSINGTRACKATab, 'numeric');
            app.TrackA_FC2.Limits = [0 500];
            app.TrackA_FC2.ValueChangedFcn = createCallbackFcn(app, @TrackA_FC2ValueChanged, true);
            app.TrackA_FC1 = uieditfield(app.PROCESSINGTRACKATab, 'numeric');
            app.TrackA_FC1.Limits = [0 500];
            app.TrackA_FC1.ValueChangedFcn = createCallbackFcn(app, @TrackA_FC1ValueChanged, true);
            app.Cuttofffrequency1Label = uilabel(app.PROCESSINGTRACKATab);
            app.Cuttofffrequency1Label.Text = 'Cuttoff frequency 1:';
            app.Cuttofffrequency2Label = uilabel(app.PROCESSINGTRACKATab);
            app.Cuttofffrequency2Label.Text = 'Cuttoff frequency 2:';
            app.EXPORTDATAAFTERMVCButton = uibutton(app.PROCESSINGTRACKATab, 'push');
            app.EXPORTDATAAFTERMVCButton.Enable = 'off';
            app.EXPORTDATAAFTERMVCButton.Text = 'EXPORT DATA AFTER MVC';
            app.DocumentationTextAreaLabel_2 = uilabel(app.PROCESSINGTRACKATab);
            app.DocumentationTextAreaLabel_2.Text = 'Documentation';
            app.DocumentationTrackA = uitextarea(app.PROCESSINGTRACKATab);
            app.DocumentationTrackA.Editable = 'off';
            app.ExportButton = uibutton(app.PROCESSINGTRACKATab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = {'Export'; ''};
            app.PROCESSINGTRACKBTab = uitab(app.TabGroup);
            app.PROCESSINGTRACKBTab.Title = 'PROCESSING TRACK B';
            app.PROCESSINGTRACKBButton = uibutton(app.PROCESSINGTRACKBTab, 'push');
            app.PROCESSINGTRACKBButton.ButtonPushedFcn = createCallbackFcn(app, @PROCESSINGTRACKBButtonPushed, true);
            app.PROCESSINGTRACKBButton.Enable = 'off';
            app.PROCESSINGTRACKBButton.Text = 'PROCESSING TRACK B';
            app.MVCNORMALISATIONTab = uitab(app.TabGroup);
            app.MVCNORMALISATIONTab.Title = 'MVC NORMALISATION';
            app.MVCNORMALISATIONButton = uibutton(app.MVCNORMALISATIONTab, 'push');
            app.MVCNORMALISATIONButton.Enable = 'off';
            app.MVCNORMALISATIONButton.Text = 'MVC NORMALISATION';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.ImportdataButton = uitogglebutton(app.ButtonGroup);
            app.ImportdataButton.Text = 'Import data';
            app.ImportdataButton.Value = true;
            app.ConversionButton = uitogglebutton(app.ButtonGroup);
            app.ConversionButton.Enable = 'off';
            app.ConversionButton.Text = 'Conversion';
            app.FastFourrierButton = uitogglebutton(app.ButtonGroup);
            app.FastFourrierButton.Enable = 'off';
            app.FastFourrierButton.Text = 'Fast Fourrier';
            app.OutlierremovalButton = uitogglebutton(app.ButtonGroup);
            app.OutlierremovalButton.Enable = 'off';
            app.OutlierremovalButton.Text = 'Outlier removal';
            app.TrackAButton = uitogglebutton(app.ButtonGroup);
            app.TrackAButton.Enable = 'off';
            app.TrackAButton.Text = 'Track A';
            app.TrackBButton = uitogglebutton(app.ButtonGroup);
            app.TrackBButton.Enable = 'off';
            app.TrackBButton.Text = 'Track B';
            app.ProcessingLabel = uilabel(app.ButtonGroup);
            app.ProcessingLabel.Text = 'Processing';
            app.imageImport = uiimage(app.ButtonGroup);
            app.imageImport.ImageSource = 'import.png';
            app.ImageConversion = uiimage(app.ButtonGroup);
            app.ImageConversion.Enable = 'off';
            app.ImageConversion.ImageSource = 'convert.png';
            app.ImageFastFourrier = uiimage(app.ButtonGroup);
            app.ImageFastFourrier.Enable = 'off';
            app.ImageFastFourrier.ImageSource = 'frequency.png';
            app.ImageOutlier = uiimage(app.ButtonGroup);
            app.ImageOutlier.Enable = 'off';
            app.ImageOutlier.ImageSource = 'remove.png';
            app.ImageA = uiimage(app.ButtonGroup);
            app.ImageA.Enable = 'off';
            app.ImageA.ImageSource = 'A.png';
            app.ImageB = uiimage(app.ButtonGroup);
            app.ImageB.Enable = 'off';
            app.ImageB.ImageSource = 'B.png';
            app.NormalisationLabel = uilabel(app.ButtonGroup);
            app.NormalisationLabel.Text = 'Normalisation';
            app.MVCNormalisationButton = uitogglebutton(app.ButtonGroup);
            app.MVCNormalisationButton.Enable = 'off';
            app.MVCNormalisationButton.Text = 'MVC Normalisation';
            app.SEMGANALYSISTOOLBOXLabel = uilabel(app.UIFigure);
            app.SEMGANALYSISTOOLBOXLabel.Text = 'SEMG ANALYSIS TOOLBOX';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = {''; ''};
            app.ExitImage = uiimage(app.UIFigure);
            app.ExitImage.ImageClickedFcn = createCallbackFcn(app, @ExitImageClicked, true);
            app.ExitImage.ImageSource = 'exit.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_PROJECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end